classdef Senales2_Lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MostrarConvolucinSwitch       matlab.ui.control.Switch
        MostrarConvolucinSwitchLabel  matlab.ui.control.Label
        JessirFlorezMateoMuozLabel    matlab.ui.control.Label
        Modo                          matlab.ui.control.DropDown
        ModoDropDownLabel             matlab.ui.control.Label
        hthnPanel                     matlab.ui.container.Panel
        Label12                       matlab.ui.control.Label
        Frecuencia22                  matlab.ui.control.NumericEditField
        FinEditFieldLabel             matlab.ui.control.Label
        Frecuencia12                  matlab.ui.control.NumericEditField
        InicioEditFieldLabel          matlab.ui.control.Label
        Amplitud2                     matlab.ui.control.NumericEditField
        Selec2                        matlab.ui.control.DropDown
        XtXnPanel                     matlab.ui.container.Panel
        Label1                        matlab.ui.control.Label
        Frecuencia2                   matlab.ui.control.NumericEditField
        PFinalEditFieldLabel          matlab.ui.control.Label
        Frecuencia1                   matlab.ui.control.NumericEditField
        PInicioLabel                  matlab.ui.control.Label
        Amplitud1                     matlab.ui.control.NumericEditField
        Selec1                        matlab.ui.control.DropDown
        botgraficas1                  matlab.ui.control.Button
        Laboratorio2ConvolucindesealesLabel  matlab.ui.control.Label
        conv2                         matlab.ui.control.UIAxes
        conv1                         matlab.ui.control.UIAxes
        graf2                         matlab.ui.control.UIAxes
        graf1                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function grafica(app)
            Am = app.Amplitud1.Value;
            Ini = app.Frecuencia1.Value;
            Fin = app.Frecuencia2.Value;
            largo1 = (app.Frecuencia2.Value-app.Frecuencia1.Value);            
            Fr = 1/largo1;
            lim = Am+1;
            if app.Modo.Value == "Continuo"
                paso = 0.02;
            else
                paso = (Fin-Ini)/20;
            end

            %-------------------------
            Am2 = app.Amplitud2.Value;
            Ini2 = app.Frecuencia12.Value;
            Fin2 = app.Frecuencia22.Value;
            largo2 = (app.Frecuencia22.Value-app.Frecuencia12.Value);
            Fr2 = 1/largo2;
            lim2 = Am2+1;
            if app.Modo.Value == "Continuo"
                paso2 = (Fin2-Ini2)/50;
            else
                paso2 = (Fin2-Ini2)/20;
            end

            %-------------------------

            switch app.Selec1.Value
                case "Señal Exponencial"
                    x1B = 0:paso:Fin-Ini;
                    x1 = Ini:paso:Fin;
                    y1 = exp(Am*x1B);
                    titulo = "y = e^ax";
                    Am = max(y1);
                    axis(app.graf1, [0 Fin+0.1 -0.2 max(y1)+1]);
                    
                case "Señal rectangular"                    
                   x1B = 0:paso2:(Fin2-Ini2);
                    x1 = Ini2:paso2:Fin2; 
                    y1 = Am2*square(Fr*2*pi*x1B);
                    axis(app.graf2, [Ini Fin -lim lim]);
                    titulo = "y = pulse";

                case "Señal Senoidal"
                    x1B = 0:paso:Fin-Ini;
                    x1 = Ini:paso:Fin; 
                    y1 = Am*sin(x1B*2*pi*Fr);
                    axis(app.graf1, [Ini Fin -lim-0.5 lim+0.5]);
                    titulo = "y = sen(x)";
                case "Señal Triangular"
                    x1B = 0:paso:Fin-Ini;
                    x1 = Ini:paso:Fin; 
                    y1 = Am*sawtooth(2 *pi*Fr*x1B,1/2);
                    axis(app.graf1, [Ini Fin -lim lim]);
                    titulo = "y = triangulo";
                case "Señal Rampa_1"
                    axis(app.graf1, [Ini Fin -0.5 lim-0.5]);
                    titulo = "rampa1";

                    x1 =Ini:paso:Fin;
                    y1 = x1*0;
                    largo=Fin-Ini;
                    pend=1/(Fin-largo*(1/3)-(Ini+(largo/3)));
                    y1(x1 <Fin-largo*(2/3))=0;
                    y1((x1>Fin-largo*(2/3)) & (x1<Fin-(largo/3)))=((x1((x1>Fin-largo*(2/3))&(x1<Fin-largo/3)))-(Ini+largo/3))*pend;
                    y1(x1>=Fin-(largo/3))=Am;

                case "Señal Rampa_2"
                    x1 =Ini:paso:Fin;
                    y1 = x1*0;
                    largo=Fin-Ini;
                    pend=1/(Fin-largo*(1/3)-(Ini+(largo/3)));
                    y1(x1 <Fin-largo*(2/3))=Am;
                    y1((x1>Fin-largo*(2/3)) & (x1<Fin-(largo/3)))=((x1((x1>Fin-largo*(2/3))&(x1<Fin-largo/3)))-(Ini+largo/3))*-pend+Am;
                    y1(x1>=Fin-(largo/3))=0;
                    
                    axis(app.graf1, [Ini Fin -0.5 lim-0.5]);
                    titulo = "rampa2";
                case "Señal Rampa_3"
                    x1 = [Ini ((Fin-Ini)/3)+Ini ((Fin-Ini)*2/3)+Ini Fin];
                    y1 = Am*[0 1 1 0];
                    axis(app.graf1, [Ini Fin -0.5 lim-0.5]);
                    titulo = "rampa3";
            end

            app.graf1.Title.String = "X(t)/X[n]: "+titulo;
            if app.Modo.Value == "Continuo"
                plot(app.graf1, x1, y1,'r','LineWidth',2);
            else
                stem(app.graf1, x1, y1,'r');
            end


            %-----------------------------------------------------
            
            rampa = false;
            switch app.Selec2.Value
                case "Señal Exponencial"
                    x2B = 0:paso2:Fin2-Ini2;
                    x2 = Ini2:paso2:Fin2;
                    y2 = exp(Am2*x2B);
                    y2r = exp(Am2*-x2B);
                    titulo2 = "y = e^ax";
                    axis(app.graf2, [0 Fin2+0.1 -0.2 max(y2)+1]);
                case "Señal rectangular"
                    x2B = 0:paso2:(Fin2-Ini2);
                    x2 = Ini2:paso2:Fin2; 
                    y2 = Am2*square(Fr2*2*pi*x2B);
                    y2r = Am2*square(Fr2*2*pi*-x2B);
                    axis(app.graf2, [Ini2 Fin2 -lim2 lim2]);
                    titulo2 = "y = pulse";
                case "Señal Senoidal"
                    x2B = 0:paso2:Fin2-Ini2;
                    x2 = Ini2:paso2:Fin2; 
                    y2 = Am2*sin(x2B*2*pi*Fr2);
                    y2r = Am2*sin(-x2B*2*pi*Fr2);
                    axis(app.graf2, [Ini2 Fin2 -lim2 lim2]);
                    titulo2 = "y = sen(x)";
                case "Señal Triangular"
                    x2B = 0:paso2:Fin2-Ini2;
                    x2 = Ini2:paso2:Fin2; 
                    y2 = Am2*sawtooth(2 *pi*Fr2*x2B,1/2);
                    y2r = Am2*sawtooth(2 *pi*Fr2*-x2B,1/2);
                    axis(app.graf2, [Ini2 Fin2 -lim2 lim2]);
                    titulo2 = "y = triangulo";
                case "Señal Rampa_1"
                 x2 =Ini2:paso:Fin2;
                 
                    y2 = x2*0;
                    largo2=Fin2-Ini2;
                    pend2=1/(Fin2-largo2*(1/3)-(Ini2+(largo2/3)));
                    y2(x2 <Fin2-largo2*(2/3))=0;
                    y2((x2>Fin2-largo2*(2/3)) & (x2<Fin2-(largo2/3)))=((x2((x2>Fin2-largo2*(2/3))&(x2<Fin2-largo2/3)))-(Ini2+largo2/3))*pend2;
                    y2(x2>=Fin2-(largo2/3))=1;

                    axis(app.graf2, [Ini2 Fin2 -0.5 lim2-0.5]);
                    titulo2 = "rampa1";
                 
       
                    y2r = fliplr(y2);

                case "Señal Rampa_2"
                       x2 =Ini2:paso:Fin2;
                    y2 = x2*0;
                    largo2=Fin2-Ini2;
                    pend2=1/(Fin2-largo2*(1/3)-(Ini2+(largo2/3)));
                    y2(x2 <Fin2-largo2*(2/3))=1;
                    y2((x2>Fin2-largo2*(2/3)) & (x2<Fin2-(largo2/3)))=((x2((x2>Fin2-largo2*(2/3))&(x2<Fin2-largo2/3)))-(Ini2+largo2/3))*-pend2+Am2;
                    y2(x2>=Fin2-(largo2/3))=0;

                    titulo2 = "rampa2";
                   
                   y2r = fliplr(y2);
                case "Señal Rampa_3"
                    x2 = [Ini2 ((Fin2-Ini2)/3)+Ini2 ((Fin2-Ini2)*2/3)+Ini2 Fin2];
                    y2 = Am2*[0 1 1 0];
                    y2r = y2;
                    axis(app.graf2, [Ini2 Fin2 -0.5 lim2-0.5]);
                    titulo2 = "rampa3";
                    rampa = true;
            end
            app.graf2.Title.String = "h(t) / h[n]: "+titulo2;
            if app.Modo.Value == "Continuo"
                plot(app.graf2, x2, y2,'b','LineWidth',2);
            else
                stem(app.graf2, x2, y2,'b');
            end


        %%==================================== CONVOLUCIÓN ====================================   
        if app.MostrarConvolucinSwitch.Value == "On"
            cla(app.conv2)
            
            st1 = abs(Ini)+largo2;
            st2 = Fin;

            if titulo2 == "y = e^ax"
                 axis(app.conv1, [-st1-1 Fin+largo2+1 -0.1 max(y2)+1]);
            else
                if Am>Am2
                    alto = Am+0.5;
                elseif Am<Am2
                    alto = Am2+0.5;
                else
                    alto = Am+0.5;
                end
                 axis(app.conv1, [-st1-1 Fin+largo2+1 -alto alto]);
            end


            m=length(y1);
            n=length(y2);
            tam = m+n-1;

            X1=[y1,zeros(1,n)];
            X2=[y2,zeros(1,m)];

            for i=1:n+m-1
                Con(i)=0;
                for j=1:m
                    if(i-j+1>0)
                        Con(i)=Con(i)+(X1(j)*X2(i-j+1));
                    else
                    end
                end
            end

            axis(app.conv2, [Ini-largo2-1 Fin+largo2+1 min(Con)-1 max(Con)+1]);
            largo3 = length(Con);
            xA = Ini-largo2:((Fin+largo2)-(Ini-largo2))/(largo3-1):Fin+largo2;
          
            length(xA)
            j = 0;
            h = animatedline(app.conv2,'LineWidth',2);
            for i = -st1:(st2+st1)/(largo3-1):st2
                j = j+1;
                x2B = i:paso2:i+Fin2-Ini2;
                if app.Modo.Value == "Continuo"
                        plot(app.conv1, x2B, y2r,'b',x1,y1,'r','LineWidth',2);
                        addpoints(h, xA(j),Con(j));
                        drawnow;
                    
                else
                    stem(app.conv1,x1,y1,'r')
                    hold(app.conv1, 'on')
                    stem(app.conv1,x2B,y2r,'b','filled')
                    hold(app.conv1, 'off');
                    stem(app.conv2,xA(j),Con(j),'m','LineWidth',1);
                    hold(app.conv2, 'on')
                    pause(0.6)
                end
              
            end
        else
            cla(app.conv1)
             cla(app.conv2)
        end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Selec1
        function Selec1ValueChanged(app, event)
        value = app.Selec1.Value;
        if value == "Señal Exponencial"
               app.Label1.Text = "Exponente";
        end
        if value ~= "Señal Exponencial"
               app.Label1.Text = "Amplitud";
        end
        end

        % Button pushed function: botgraficas1
        function botgraficas1ButtonPushed(app, event)
            cla(app.graf1)
            cla(app.graf2)
            cla(app.conv1)
            grafica(app)

        end

        % Callback function
        function Selec1Clicked(app, event)
         
            
        end

        % Callback function
        function MostrarConvolucionesButtonPushed(app, event)
              cla(app.conv1)
            grafica(app)
            convo2(app)

        end

        % Value changed function: Selec2
        function Selec2ValueChanged(app, event)
            value = app.Selec2.Value;
            if value == "Señal Exponencial"
                app.Label12.Text = "Exponente";
            end
            if value ~= "Señal Exponencial"
                app.Label12.Text = "Amplitud";
            end
        end

        % Value changed function: MostrarConvolucinSwitch
        function MostrarConvolucinSwitchValueChanged(app, event)
            %value = app.MostrarConvolucinSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graf1 = uiaxes(app.UIFigure);
            title(app.graf1, 'X(t)/X[n]')
            app.graf2 = uiaxes(app.UIFigure);
            title(app.graf2, 'h(t) / h[n]')
            app.conv1 = uiaxes(app.UIFigure);
            title(app.conv1, 'Convolución')
            xlabel(app.conv1, 'X')
            ylabel(app.conv1, 'Y')
            zlabel(app.conv1, 'Z')
            app.conv2 = uiaxes(app.UIFigure);
            title(app.conv2, 'Convolución')
            xlabel(app.conv2, 'X')
            ylabel(app.conv2, 'Y')
            zlabel(app.conv2, 'Z')
            app.Laboratorio2ConvolucindesealesLabel = uilabel(app.UIFigure);
            app.Laboratorio2ConvolucindesealesLabel.Text = 'Laboratorio 2 - Convolución de señales';
            app.botgraficas1 = uibutton(app.UIFigure, 'push');
            app.botgraficas1.ButtonPushedFcn = createCallbackFcn(app, @botgraficas1ButtonPushed, true);
            app.botgraficas1.Text = 'Ejecutar';
            app.XtXnPanel = uipanel(app.UIFigure);
            app.XtXnPanel.Title = 'X(t)/X[n]';
            app.Selec1 = uidropdown(app.XtXnPanel);
            app.Selec1.Items = {'Señal Exponencial', 'Señal Senoidal', 'Señal Triangular', 'Señal rectangular', 'Señal Rampa_1', 'Señal Rampa_2', 'Señal Rampa_3'};
            app.Selec1.ValueChangedFcn = createCallbackFcn(app, @Selec1ValueChanged, true);
            app.Selec1.Value = 'Señal Exponencial';
            app.Amplitud1 = uieditfield(app.XtXnPanel, 'numeric');
            app.Amplitud1.Tag = 'hola';
            app.PInicioLabel = uilabel(app.XtXnPanel);
            app.PInicioLabel.Text = 'P. Inicio';
            app.Frecuencia1 = uieditfield(app.XtXnPanel, 'numeric');
            app.PFinalEditFieldLabel = uilabel(app.XtXnPanel);
            app.PFinalEditFieldLabel.Text = 'P. Final';
            app.Frecuencia2 = uieditfield(app.XtXnPanel, 'numeric');
            app.Label1 = uilabel(app.XtXnPanel);
            app.Label1.Text = 'Exponente';
            app.hthnPanel = uipanel(app.UIFigure);
            app.hthnPanel.Title = 'h(t) / h[n]';
            app.Selec2 = uidropdown(app.hthnPanel);
            app.Selec2.Items = {'Señal Exponencial', 'Señal Senoidal', 'Señal Triangular', 'Señal rectangular', 'Señal Rampa_1', 'Señal Rampa_2', 'Señal Rampa_3'};
            app.Selec2.ValueChangedFcn = createCallbackFcn(app, @Selec2ValueChanged, true);
            app.Selec2.Value = 'Señal Exponencial';
            app.Amplitud2 = uieditfield(app.hthnPanel, 'numeric');
            app.InicioEditFieldLabel = uilabel(app.hthnPanel);
            app.InicioEditFieldLabel.Text = 'Inicio';
            app.Frecuencia12 = uieditfield(app.hthnPanel, 'numeric');
            app.FinEditFieldLabel = uilabel(app.hthnPanel);
            app.FinEditFieldLabel.Text = 'Fin';
            app.Frecuencia22 = uieditfield(app.hthnPanel, 'numeric');
            app.Label12 = uilabel(app.hthnPanel);
            app.Label12.Text = 'Exponente';
            app.ModoDropDownLabel = uilabel(app.UIFigure);
            app.ModoDropDownLabel.Text = 'Modo';
            app.Modo = uidropdown(app.UIFigure);
            app.Modo.Items = {'Continuo', 'Discreto'};
            app.Modo.Value = 'Continuo';
            app.JessirFlorezMateoMuozLabel = uilabel(app.UIFigure);
            app.JessirFlorezMateoMuozLabel.Text = 'Jessir Florez, Mateo Muñoz, ...';
            app.MostrarConvolucinSwitchLabel = uilabel(app.UIFigure);
            app.MostrarConvolucinSwitchLabel.Text = 'Mostrar Convolución';
            app.MostrarConvolucinSwitch = uiswitch(app.UIFigure, 'slider');
            app.MostrarConvolucinSwitch.ValueChangedFcn = createCallbackFcn(app, @MostrarConvolucinSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Senales2_Lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end